classdef cardio_raw_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LoaddataPanel       matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        BrowseField         matlab.ui.control.EditField
        LoadButton          matlab.ui.control.Button
        BrowseButton        matlab.ui.control.Button
        WellAxes            matlab.ui.control.UIAxes
        LoadselectedButton  matlab.ui.control.Button
        ColorScalePanel     matlab.ui.container.Panel
        CMaxEditField       matlab.ui.control.NumericEditField
        CMinEditField       matlab.ui.control.NumericEditField
        minLabel            matlab.ui.control.Label
        maxLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        wlmap                               % 12 wells of wavelength map (time, x, y)
        time                                % timesteps in seconds
        well_selection = zeros([1,12])      % selected wells (12 element vector with 0s and 1s at selected/non-selected wells respectively)
        cMin                                % colorbar min value
        cMax                                % colorbar max value
        CallingApp                          % the calling CardioRaw eval software
    end
    
    methods (Access = private)
        
        function refresh_target_plot(app, wlmap, well_selection)
            % refresh content of BiosensorAxes
            cla(app.WellAxes);
%             hold(app.BiosensorAxes,'on');
            app.WellAxes.XLim=[0 size(app.wlmap,3)+1];
            app.WellAxes.YLim=[0 size(app.wlmap,2)+1];
            imagesc(app.WellAxes,squeeze(wlmap(end,:,:)));
            pbaspect(app.WellAxes,[4 3 1]);

            for i=1:length(well_selection)
                if well_selection(i)==1
                    [x_well, y_well] =  ind2sub([4,3],i);
                    rectangle(app.WellAxes,"Position",[0.25*x_well+(x_well-1)*80,0.25*y_well+(y_well-1)*80,80,80],'EdgeColor','r','LineWidth',2);
                end
            end
            
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            app.CallingApp=calling_app;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            colorbar(app.WellAxes);                        %add colorbar to axes
            caxis(app.WellAxes,[829,833]);                 %set colorbar limits
            
            %disable interactivity of BiosensorAxes
            app.WellAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.WellAxes);
            set(app.WellAxes,'visible','off')
            
            colormap(app.WellAxes,"parula");               %set colorscale
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            dname = uigetdir();
            cd(dname); 
            app.BrowseField.Value = dname;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.wlmap,app.time] = load_measurement(app.BrowseField.Value);
            app.refresh_target_plot(app.wlmap,app.well_selection);
        end

        % Value changed function: CMinEditField
        function CMinEditFieldValueChanged(app, event)
            caxis(app.WellAxes,[app.CMinEditField.Value,app.CMaxEditField.Value]);       %set colorbar limits
        end

        % Value changed function: CMaxEditField
        function CMaxEditFieldValueChanged(app, event)
            caxis(app.WellAxes,[app.CMinEditField.Value,app.CMaxEditField.Value]);       %set colorbar limits
        end

        % Window button down function: UIFigure
        function SelectWellByClick(app, event)
            %detect clicks on the figure for cell selection functionality
            
            tmp = app.WellAxes.CurrentPoint;
            %create difference vector with tmp and m
            x_well = round(floor(tmp(1,1)/80));
            y_well = round(floor(tmp(1,2)/80));
            
            if app.well_selection(x_well+1+4*y_well)==0
                app.well_selection(x_well+1+4*y_well)=1;
            else
                app.well_selection(x_well+1+4*y_well)=0;
            end
            app.refresh_target_plot(app.wlmap,app.well_selection)
            
            
        end

        % Button pushed function: LoadselectedButton
        function LoadselectedButtonPushed(app, event)
            [x_well, y_well] =  ind2sub([4,3],find(app.well_selection));
            
            startX=1+(x_well-1)*80;
            endX = startX+79;
            startY=1+(y_well-1)*80;
            endY = startY+79;

            writemat = app.wlmap(:,startY:endY,startX:endX);
%             permute is for correct orientation
            oriented = permute(writemat,[2,3,1]);
            app.CallingApp.pic = oriented;
            app.CallingApp.time = app.time;
            
            app.CallingApp.LoadRAWButton.Enable = 'on';
            delete(app)  
            
%             flattened = reshape(oriented,[size(oriented,1),size(oriented,2)*size(oriented,3)]); 
%             catenated = cat(2,app.time,flattened);
%             writematrix(catenated,'test_export.xlsx');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.LoadRAWButton.Enable = 'on';
            delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @SelectWellByClick, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoaddataPanel = uipanel(app.GridLayout);
            app.LoaddataPanel.Title = 'Load data';
            app.GridLayout2 = uigridlayout(app.LoaddataPanel);
            app.BrowseField = uieditfield(app.GridLayout2, 'text');
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.BrowseButton = uibutton(app.GridLayout2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.WellAxes = uiaxes(app.GridLayout);
            title(app.WellAxes, 'Title')
            xlabel(app.WellAxes, 'X')
            ylabel(app.WellAxes, 'Y')
            app.LoadselectedButton = uibutton(app.GridLayout, 'push');
            app.LoadselectedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadselectedButtonPushed, true);
            app.LoadselectedButton.Text = 'Load selected';
            app.ColorScalePanel = uipanel(app.GridLayout);
            app.ColorScalePanel.Title = 'ColorScale';
            app.CMaxEditField = uieditfield(app.ColorScalePanel, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CMaxEditFieldValueChanged, true);
            app.CMaxEditField.Value = 833;
            app.CMinEditField = uieditfield(app.ColorScalePanel, 'numeric');
            app.CMinEditField.ValueChangedFcn = createCallbackFcn(app, @CMinEditFieldValueChanged, true);
            app.CMinEditField.Value = 829;
            app.minLabel = uilabel(app.ColorScalePanel);
            app.minLabel.Text = 'min.';
            app.maxLabel = uilabel(app.ColorScalePanel);
            app.maxLabel.Text = 'max.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cardio_raw_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end