classdef lin_corr_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AvgAxes               matlab.ui.control.UIAxes
        ApplyButton           matlab.ui.control.Button
        StarttimeSliderLabel  matlab.ui.control.Label
        StartTimeSlider       matlab.ui.control.Slider
        EndtimeSliderLabel    matlab.ui.control.Label
        EndTimeSlider         matlab.ui.control.Slider
        StartTimeEditField    matlab.ui.control.EditField
        EndTimeEditField      matlab.ui.control.EditField
        StartTimeLabel        matlab.ui.control.Label
        EndTimeLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        avgWS
        time
        start_time
        end_time
        CallingApp
    end
    
    methods (Access = private)
        
        function refresh_plot(app,start_time,end_time)
            cla(app.AvgAxes);
            plot(app.AvgAxes,app.time./60,app.avgWS);
            line(app.AvgAxes,[start_time/60,start_time/60],app.AvgAxes.YLim,'Color','b')
            line(app.AvgAxes,[end_time/60,end_time/60],app.AvgAxes.YLim,'Color','r')
            legend(app.AvgAxes,'average signal','start time','end time');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app, time, avgWS)
            app.CallingApp=calling_app;
            app.avgWS=avgWS;
            app.time=time;
            app.StartTimeSlider.Limits=[time(1),time(end)];
            app.EndTimeSlider.Limits=[time(1),time(end)];
            app.StartTimeSlider.Value=time(1);
            app.EndTimeSlider.Value=time(end);
            app.StartTimeEditField.Value=num2str(time(1)/60);
            app.EndTimeEditField.Value=num2str(time(end)/60);
            app.refresh_plot(time(1),time(end));
            if strcmp(app.CallingApp.mode,'WL')
                ylabel(app.AvgAxes,'average WL [nm]')
            else
                ylabel(app.AvgAxes,'average WS [pm]')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.LinearcorrectionButton.Enable = 'on';
            delete(app)            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if strcmp(app.CallingApp.mode,'WL')
                app.CallingApp.pic_corr = 1000.*linear_baseline_correction(app.StartTimeSlider.Value,app.EndTimeSlider.Value,app.avgWS,app.CallingApp.pic,app.time); %convert to pm after correction
                app.CallingApp.mode = 'WS';
            else
                app.CallingApp.pic_corr = linear_baseline_correction(app.StartTimeSlider.Value,app.EndTimeSlider.Value,app.avgWS,app.CallingApp.pic,app.time);
            end
            for i=1:size(app.time,1)
                app.CallingApp.avgWS(i)=mean(reshape(app.CallingApp.pic_corr(:,:,i),1,[]));
            end
            app.CallingApp.refresh_avg_kin_plot();
            app.CallingApp.LinearcorrectionButton.Enable = 'on';
            delete(app)  
        end

        % Value changing function: StartTimeSlider
        function StartTimeSliderValueChanging(app, event)
          app.StartTimeEditField.Value = num2str(event.Value/60);
          app.refresh_plot(event.Value,app.EndTimeSlider.Value);
        end

        % Value changing function: EndTimeSlider
        function EndTimeSliderValueChanging(app, event)
            app.EndTimeEditField.Value = num2str(event.Value/60);
            app.refresh_plot(app.StartTimeSlider.Value,event.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AvgAxes = uiaxes(app.UIFigure);
            title(app.AvgAxes, '')
            xlabel(app.AvgAxes, 'time (min)')
            ylabel(app.AvgAxes, 'average WS (pm)')
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StarttimeSliderLabel = uilabel(app.UIFigure);
            app.StarttimeSliderLabel.Text = 'Start time';
            app.StartTimeSlider = uislider(app.UIFigure);
            app.StartTimeSlider.MajorTicks = [];
            app.StartTimeSlider.MajorTickLabels = {''};
            app.StartTimeSlider.ValueChangingFcn = createCallbackFcn(app, @StartTimeSliderValueChanging, true);
            app.StartTimeSlider.MinorTicks = [];
            app.EndtimeSliderLabel = uilabel(app.UIFigure);
            app.EndtimeSliderLabel.Text = 'End time';
            app.EndTimeSlider = uislider(app.UIFigure);
            app.EndTimeSlider.MajorTicks = [];
            app.EndTimeSlider.ValueChangingFcn = createCallbackFcn(app, @EndTimeSliderValueChanging, true);
            app.EndTimeSlider.MinorTicks = [];
            app.StartTimeEditField = uieditfield(app.UIFigure, 'text');
            app.EndTimeEditField = uieditfield(app.UIFigure, 'text');
            app.StartTimeLabel = uilabel(app.UIFigure);
            app.StartTimeLabel.Text = 'min';
            app.EndTimeLabel = uilabel(app.UIFigure);
            app.EndTimeLabel.Text = 'min';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lin_corr_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end