classdef load_raw_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RawAxes                matlab.ui.control.UIAxes
        LoadmeasurementButton  matlab.ui.control.Button
        WLmaxEditFieldLabel    matlab.ui.control.Label
        WLmaxEditField         matlab.ui.control.EditField
        WLminEditFieldLabel    matlab.ui.control.Label
        WLminEditField         matlab.ui.control.EditField
        nmLabel                matlab.ui.control.Label
        nmLabel_2              matlab.ui.control.Label
    end

    
    properties (Access = public)
        raw_data % Description
        cmin=828
        cmax=835
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmeasurementButton
        function LoadmeasurementButtonPushed(app, event)
            dname = uigetdir('D:\KFKI');
            app.raw_data = load_raw_measurement(dname);
            imagesc(app.RawAxes,squeeze(app.raw_data(end,:,:)));
        end

        % Value changed function: WLmaxEditField
        function WLmaxEditFieldValueChanged(app, event)
            app.cmax = str2num(app.WLmaxEditField.Value);
            imagesc(app.RawAxes,squeeze(app.raw_data(end,:,:)));
            caxis(app.RawAxes,[app.cmin,app.cmax]);
        end

        % Value changed function: WLminEditField
        function WLminEditFieldValueChanged(app, event)
            app.cmin = str2num(app.WLminEditField.Value);
            imagesc(app.RawAxes,squeeze(app.raw_data(end,:,:)));
            [app.cmin,app.cmax]
            caxis(app.RawAxes,[app.cmin,app.cmax]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RawAxes = uiaxes(app.UIFigure);
            title(app.RawAxes, '')
            xlabel(app.RawAxes, '')
            ylabel(app.RawAxes, '')
            app.RawAxes.XTick = [];
            app.RawAxes.YTick = [];
            app.LoadmeasurementButton = uibutton(app.UIFigure, 'push');
            app.LoadmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmeasurementButtonPushed, true);
            app.LoadmeasurementButton.Text = 'Load measurement';
            app.WLmaxEditFieldLabel = uilabel(app.UIFigure);
            app.WLmaxEditFieldLabel.Text = 'WL max';
            app.WLmaxEditField = uieditfield(app.UIFigure, 'text');
            app.WLmaxEditField.ValueChangedFcn = createCallbackFcn(app, @WLmaxEditFieldValueChanged, true);
            app.WLmaxEditField.Value = '835';
            app.WLminEditFieldLabel = uilabel(app.UIFigure);
            app.WLminEditFieldLabel.Text = 'WL min';
            app.WLminEditField = uieditfield(app.UIFigure, 'text');
            app.WLminEditField.ValueChangedFcn = createCallbackFcn(app, @WLminEditFieldValueChanged, true);
            app.WLminEditField.Value = '828';
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.UIFigure);
            app.nmLabel_2.Text = 'nm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = load_raw_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end