classdef global_bg_corr_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ThresholdSliderLabel  matlab.ui.control.Label
        ThresholdSlider       matlab.ui.control.Slider
        ApplyButton           matlab.ui.control.Button
        SubplotPanel          matlab.ui.container.Panel
        pmLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp          % The main app (cardio_eval_app)
        last_frame          % Last frame of the loaded measurement in main app
        threshold           % The selected threshold for global background correction
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, last_frame)
            app.CallingApp = main_app;
            app.last_frame = last_frame;
            
            app.SubplotPanel.AutoResizeChildren = 'off';
            ax1 = subplot(1,2,1,'Parent',app.SubplotPanel);
            ax2 = subplot(1,2,2,'Parent',app.SubplotPanel);
            
            imagesc(ax1,last_frame);
            imagesc(ax2,last_frame);
            
            set(ax1,'visible','off')
            set(ax2,'visible','off')
            pbaspect(ax1,[1 1 1]);
            pbaspect(ax2,[1 1 1]);
            app.ThresholdSlider.Limits = [min(last_frame(:)),max(last_frame(:))];
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            
            ax2 = subplot(1,2,2,'Parent',app.SubplotPanel);
            imagesc(ax2,app.last_frame<changingValue);
            set(ax2,'visible','off')
            pbaspect(ax2,[1 1 1]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            app.CallingApp.GlobalbackgroundcorrectionButton.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if strcmp(app.CallingApp.mode,'WL')
                app.CallingApp.pic_corr = 1000.*global_background_correction(app.CallingApp.pic,app.ThresholdSlider.Value); %convert to pm after correction
                app.CallingApp.mode = 'WS';
            else
                app.CallingApp.pic_corr = global_background_correction(app.CallingApp.pic,app.ThresholdSlider.Value);
            end
            
            for i=1:size(app.CallingApp.time,1)
                app.CallingApp.avgWS(i)=mean(reshape(app.CallingApp.pic_corr(:,:,i),1,[]));
            end
            
            app.CallingApp.refresh_avg_kin_plot();
            app.CallingApp.detection_th = app.ThresholdSlider.Value;
            
            % Delete the dialog box  
            app.CallingApp.GlobalbackgroundcorrectionButton.Enable = 'on';
            delete(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SubplotPanel = uipanel(app.UIFigure);
            app.pmLabel = uilabel(app.UIFigure);
            app.pmLabel.Text = 'pm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = global_bg_corr_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end