classdef local_bg_corr_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectcellsPanel               matlab.ui.container.Panel
        ROIAxes                        matlab.ui.control.UIAxes
        DetectionthresholdSliderLabel  matlab.ui.control.Label
        DetectThSlider                 matlab.ui.control.Slider
        ROIradiusSliderLabel           matlab.ui.control.Label
        ROIRadSlider                   matlab.ui.control.Slider
        IndividualcorrectionPanel      matlab.ui.container.Panel
        CorrAxes                       matlab.ui.control.UIAxes
        CellnumberDropDownLabel        matlab.ui.control.Label
        CellSelectionDropdown          matlab.ui.control.DropDown
        CellthresholdSliderLabel       matlab.ui.control.Label
        CellThSlider                   matlab.ui.control.Slider
        BGVisAxes                      matlab.ui.control.UIAxes
        GlobalthresholdCheckBox        matlab.ui.control.CheckBox
        pmEditFieldLabel               matlab.ui.control.Label
        CellThValueEditField           matlab.ui.control.NumericEditField
        ApplyButton                    matlab.ui.control.Button
        CommBox                        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        CallingApp                      % The main app (cardio_eval_app)
        pic                             % The 3D data structure (2 spatial and 1 time) containing a single Cardio biosensor signal
        last_frame                      % Last frame of the loaded measurement in main app
        thresholds                      % individual threshold selected by user
        ROIrad=200                      % radius of the ROI (um)
        max_coord=[]                       % max cooridnates of detected cells
        ROIs                            % restricted ROIs produced by the intersection of circle and Voronoi ROIs
        cell_signal                     % the currently selected cells part of the measurement matrix
        cell_avg_bg_signal              % (background region)average background signal of selected cell
        cell_avg_signal_corr=[]         % (ROI)average corrected signal of selected cell
        cell_ROI_mask
        cell_BG_mask                    % binary mask of current background
        ind_corr_TH                     % vector that hold all the individual correction threshold values
        tmp_signal_corr                 % the given cells temporary corrected ROI signal 
        pic_corr                        % the corrected signal matrix to be returned from correction process
        cell_idx=1                      % currently selected cell for plotting
    end
    
    methods (Access = private)
        
        function refresh_target_plot(app)
            cla(app.ROIAxes);
            hold(app.ROIAxes,'on');
            app.ROIAxes.XLim=[1 size(app.last_frame,1)];
            app.ROIAxes.YLim=[1 size(app.last_frame,1)];
            imagesc(app.ROIAxes,app.last_frame);
            
            scatter(app.ROIAxes,app.max_coord(:,1),app.max_coord(:,2),'r.');
            for i=1:size(app.max_coord,1)
                b = bwboundaries(app.ROIs==i);
                boundary = b{1};
                plot(app.ROIAxes,boundary(:,2), boundary(:,1), 'k', 'LineWidth', 1);
            end
            text(app.ROIAxes,app.max_coord(:,1),app.max_coord(:,2),num2str(app.CallingApp.cellLabels'),'VerticalAlignment','bottom');
        end
        
        function refresh_single_cell_plot(app)
            cla(app.CorrAxes,'reset');
            hold(app.CorrAxes,'on');
            if strcmp(app.CallingApp.mode,'WL')
                yyaxis(app.CorrAxes,'left');
                ylabel(app.CorrAxes,'average WL [nm]');
            else
                ylabel(app.CorrAxes,'average WS [pm]')
            end
            plot(app.CorrAxes,app.CallingApp.time./60,app.cell_avg_bg_signal);
            title(app.CorrAxes,'Average kinetics of cell region')
            legend(app.CorrAxes,'background signal','Location','southwest')
            if ~isempty(app.cell_avg_signal_corr)
                if strcmp(app.CallingApp.mode,'WL')
                    yyaxis(app.CorrAxes,'right');
                    ylabel(app.CorrAxes,'average WS [pm]');
                    plot(app.CorrAxes,app.CallingApp.time./60,1000*app.cell_avg_signal_corr);
                else
                    plot(app.CorrAxes,app.CallingApp.time./60,app.cell_avg_signal_corr);
                end
                legend(app.CorrAxes,'background signal','corrected signal','Location','southwest');
            end
            pbaspect(app.CorrAxes,[1 1 1]);
            imagesc(app.BGVisAxes,app.cell_BG_mask);
        end
        
        function detect_cells_inapp(app)
            %detect cells and return [coordinates,labels, Voronoi Mask]
            [app.max_coord,app.CallingApp.cellLabels,app.CallingApp.VMask,~] = detect_cells(app.DetectThSlider.Value,app.last_frame,app.CallingApp.raw_pix_size);
        end
        
        function calc_ROIs(app)
            %calculate ROIs by the intersection of Voronoi and circular regions
            app.ROIs = zeros(size(app.CallingApp.VMask)); %initialize with nans
            for i=1:length(app.CallingApp.cellLabels)
                circle_ROI = local_region(app.max_coord(i,:),app.ROIrad,size(app.ROIs,1),app.CallingApp.raw_pix_size);
                app.ROIs(circle_ROI & (app.CallingApp.VMask==i)) = i;
            end
            app.CellSelectionDropdown.Items=strsplit(num2str(1:size(app.max_coord,1)));
        end
              
        function calc_corr(app,cell_idx)
            app.cell_ROI_mask = app.ROIs==cell_idx; %ROI mask of given cell
            TH = app.ind_corr_TH(cell_idx); %threshold for selected cell
            
            %3D signal of selected cell
            app.cell_signal = app.CallingApp.pic.*repmat(app.cell_ROI_mask,[1,1,size(app.CallingApp.pic,3)]);
            
            cell_last_frame = app.cell_signal(:,:,end); %last frame ROI with signal
            
            linind = find(cell_last_frame); % find nonzero element indices
            background_ind = cell_last_frame(linind)<=TH; %find pixels that are smaller than individual threshold
            bg_linind = linind(background_ind); %nonzero background pixel linear indices
            
            app.cell_BG_mask=zeros(size(cell_last_frame));
            app.cell_BG_mask(bg_linind)=1;

            app.cell_avg_bg_signal=[];
            for t=1:size(app.cell_signal,3)
                tmp_signal= squeeze(app.cell_signal(:,:,t));
                app.cell_avg_bg_signal(t)= mean(tmp_signal(bg_linind));
            end
            
            %creating background signal matrix  by repeating average signal
            tmp_background = repmat(reshape(app.cell_avg_bg_signal,[1,1,size(app.CallingApp.pic,3)]),[size(app.CallingApp.pic,1),size(app.CallingApp.pic,2),1]);

            %average of corrected ROI signal
            app.tmp_signal_corr = app.cell_signal-tmp_background;
            app.tmp_signal_corr = app.tmp_signal_corr.*repmat(app.cell_ROI_mask,[1,1,size(app.CallingApp.pic,3)]); % restrict background correction to the actual ROI

            for t=1:size(app.cell_signal,3)
                app.cell_avg_signal_corr(t)= mean(nonzeros(app.tmp_signal_corr(:,:,t)));
            end
            
        end
        
        function set_indi_THs(app,cell_num,TH)
            if app.GlobalthresholdCheckBox.Value % if checked set all individual THs
                app.ind_corr_TH=TH*ones(size(app.CallingApp.cellLabels));
            else % if not checked set only cell_num's individual TH
                app.ind_corr_TH(cell_num)=TH;
            end
        end
        
        function validate_th(app)
            %CHECK!
            for i=1:length(app.CallingApp.cellLabels)
                
                tmp_mask = app.ROIs==i; %ROI mask of given cell
                tmp_mask=double(tmp_mask);
                tmp_mask(tmp_mask==0)=nan;
                
                %3D signal of selected cell
                last_cell_frame = squeeze(app.CallingApp.pic(:,:,end)).*tmp_mask;
                if app.ind_corr_TH(i)< min(last_cell_frame(:))
                    app.ind_corr_TH(i) = min(last_cell_frame(:));
                    app.CellThSlider.Value = app.ind_corr_TH(i);
                    app.CellThValueEditField.Value = app.ind_corr_TH(i);
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, pic)
            app.CallingApp = main_app;
            app.last_frame = pic(:,:,end);

            imagesc(app.ROIAxes,app.last_frame);
            set(app.ROIAxes,'visible','off')
            pbaspect(app.ROIAxes,[1 1 1]);
            
            pbaspect(app.CorrAxes,[1 1 1]);
            
            set(app.BGVisAxes,'visible','off')
            pbaspect(app.BGVisAxes,[1 1 1]);
            
            app.DetectThSlider.Limits = [min(app.last_frame(:)),max(app.last_frame(:))];
            app.DetectThSlider.Value = min(app.last_frame(:));
            
            app.ROIRadSlider.Limits=[25,40*25];
            app.ROIRadSlider.Value = app.ROIrad;
            
            app.CellThSlider.Limits = [min(app.last_frame,[],'all'),max(app.last_frame,[],'all')];
            app.CellThSlider.Value = app.DetectThSlider.Value;
            app.CellThValueEditField.Value = app.DetectThSlider.Value;
            
            
            detect_cells_inapp(app);
            calc_ROIs(app);
            
            %set initial value of the cellTH vector as the mean value of the last frame
            app.ind_corr_TH = app.DetectThSlider.Value*ones(size(app.CallingApp.cellLabels));
           
            calc_corr(app,1);
            
            %refresh plots
            app.refresh_target_plot();
            app.refresh_single_cell_plot();
            
            %initialize corrected signal matrix with zeros
            app.pic_corr=zeros(size(pic));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ComplexBackgroundcorrectionButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: DetectThSlider
        function DetectThSliderValueChanged(app, event)
            detect_cells_inapp(app);
            calc_ROIs(app);
            app.refresh_target_plot();
        end

        % Value changed function: CellSelectionDropdown
        function CellSelectionDropdownValueChanged(app, event)
            app.cell_idx = str2num(app.CellSelectionDropdown.Value);
%             calc_cell_data(app,app.cell_idx);
            current_TH = app.ind_corr_TH(app.cell_idx);
%             app.CellThSlider.Limits = [min(nonzeros(app.cell_signal)),max(nonzeros(app.cell_signal))];
%             app.CellThSlider.Limits = [min(app.last_frame,[],'all'),max(app.last_frame,[],'all')];
            calc_corr(app,app.cell_idx);
            app.CellThValueEditField.Value=current_TH;
            app.CellThSlider.Value=current_TH;
            refresh_single_cell_plot(app);
        end

        % Value changing function: CellThSlider
        function CellThSliderValueChanging(app, event)
            TH=event.Value;

            set_indi_THs(app,app.cell_idx,TH);
            validate_th(app);
            calc_corr(app,app.cell_idx);
            refresh_single_cell_plot(app);
            app.CellThValueEditField.Value=TH;
        end

        % Value changing function: ROIRadSlider
        function ROIRadSliderValueChanging(app, event)
            app.ROIrad = 25*round(event.Value/25);
            calc_ROIs(app);
            app.refresh_target_plot();
        end

        % Value changed function: GlobalthresholdCheckBox
        function GlobalthresholdCheckBoxValueChanged(app, event)
            set_indi_THs(app,str2num(app.CellSelectionDropdown.Value),app.CellThValueEditField.Value);
            refresh_single_cell_plot(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %creating progress bar     
            f = app.UIFigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Evaluating data...');
            cell_num=length(app.CallingApp.cellLabels);
            
            for i=1:cell_num
                tic
                calc_corr(app,i);
                tmp = cat(4,app.pic_corr,app.tmp_signal_corr);
                app.pic_corr = nansum(tmp,4);
                
                %create max shifts on last frame for calling app
%                 app.CallingApp.maxSHIFT(i)=app.pic_corr(app.max_coord(i,1),app.max_coord(i,2),end);
                
                d.Value=i/cell_num;
                T1=toc;
                d.Message=['Signal correction in progress...Estimated time left: ',strcat(num2str(T1* (cell_num-i),'%.0f'),'s')];
            end
            
%             app.CallingApp.VMask=app.ROIs;
            
            %if the input file was WL type, convert it to WS after correction
            if strcmp(app.CallingApp.mode,'WL')
                disp('convert to pm')
                app.CallingApp.pic_corr = 1000*app.pic_corr; %convert to pm after correction
                app.CallingApp.mode = 'WS';
            else
                app.CallingApp.pic_corr=app.pic_corr;
            end
            
            %create average corrected signal for calling app
            for t=1:size(app.cell_signal,3)
                app.CallingApp.avgWS(t)= mean(app.CallingApp.pic_corr(:,:,t),'all');
            end
            app.CallingApp.mode = 'WS';
            app.CallingApp.refresh_avg_kin_plot();
            app.CallingApp.update_colorscale(app.pic_corr);
            
            app.CallingApp.ComplexBackgroundcorrectionButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: CellThSlider
        function CellThSliderValueChanged(app, event)
            validate_th(app);
            calc_corr(app,app.cell_idx);
            refresh_single_cell_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectcellsPanel = uipanel(app.UIFigure);
            app.SelectcellsPanel.Title = 'Select cells';
            app.ROIAxes = uiaxes(app.SelectcellsPanel);
            title(app.ROIAxes, 'ROI selection on last frame')
            xlabel(app.ROIAxes, 'X')
            ylabel(app.ROIAxes, 'Y')
            app.DetectionthresholdSliderLabel = uilabel(app.SelectcellsPanel);
            app.DetectionthresholdSliderLabel.Text = 'Detection threshold';
            app.DetectThSlider = uislider(app.SelectcellsPanel);
            app.DetectThSlider.ValueChangedFcn = createCallbackFcn(app, @DetectThSliderValueChanged, true);
            app.ROIradiusSliderLabel = uilabel(app.SelectcellsPanel);
            app.ROIradiusSliderLabel.Text = 'ROI radius';
            app.ROIRadSlider = uislider(app.SelectcellsPanel);
            app.ROIRadSlider.ValueChangingFcn = createCallbackFcn(app, @ROIRadSliderValueChanging, true);
            app.IndividualcorrectionPanel = uipanel(app.UIFigure);
            app.IndividualcorrectionPanel.Title = 'Individual correction';
            app.CorrAxes = uiaxes(app.IndividualcorrectionPanel);
            title(app.CorrAxes, 'Individual correction')
            xlabel(app.CorrAxes, 'X')
            ylabel(app.CorrAxes, 'Y')
            app.CellnumberDropDownLabel = uilabel(app.IndividualcorrectionPanel);
            app.CellnumberDropDownLabel.Text = 'Cell number';
            app.CellSelectionDropdown = uidropdown(app.IndividualcorrectionPanel);
            app.CellSelectionDropdown.Items = {'1', '2', '3', '4'};
            app.CellSelectionDropdown.ValueChangedFcn = createCallbackFcn(app, @CellSelectionDropdownValueChanged, true);
            app.CellSelectionDropdown.Value = '1';
            app.CellthresholdSliderLabel = uilabel(app.IndividualcorrectionPanel);
            app.CellthresholdSliderLabel.Text = 'Cell threshold';
            app.CellThSlider = uislider(app.IndividualcorrectionPanel);
            app.CellThSlider.ValueChangedFcn = createCallbackFcn(app, @CellThSliderValueChanged, true);
            app.CellThSlider.ValueChangingFcn = createCallbackFcn(app, @CellThSliderValueChanging, true);
            app.CellThSlider.MinorTicks = [];
            app.BGVisAxes = uiaxes(app.IndividualcorrectionPanel);
            title(app.BGVisAxes, 'Title')
            xlabel(app.BGVisAxes, 'X')
            ylabel(app.BGVisAxes, 'Y')
            app.GlobalthresholdCheckBox = uicheckbox(app.IndividualcorrectionPanel);
            app.GlobalthresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalthresholdCheckBoxValueChanged, true);
            app.GlobalthresholdCheckBox.Text = 'Global threshold';
            app.GlobalthresholdCheckBox.Value = true;
            app.pmEditFieldLabel = uilabel(app.IndividualcorrectionPanel);
            app.pmEditFieldLabel.Text = 'pm';
            app.CellThValueEditField = uieditfield(app.IndividualcorrectionPanel, 'numeric');
            app.CellThValueEditField.Editable = 'off';
            app.ApplyButton = uibutton(app.IndividualcorrectionPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CommBox = uieditfield(app.UIFigure, 'text');
            app.CommBox.Editable = 'off';
            app.CommBox.Value = 'Communication box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = local_bg_corr_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end